classdef hammingcodes_IVM_DI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GenerateandEncodeButton  matlab.ui.control.Button
        FlipBitButton            matlab.ui.control.Button
        Label_2                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        EditField_3              matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        bits; % Property used for transfering the bits from the encode function to the change bit and decode function since
             % in matlab they can be used as global variables
        n;
        C_i; % Control bits place in string
        D_i; % Data bits place in string
        controlBits;
        dataBits;
    end
    
    methods (Access = public)
        
        function genAndEncode(app)
            app.n = app.EditField_3.Value; % Nr of parity bits
            len = 2^app.n - 1;% Nr of total bits
            nr_dB = len - app.n; % Nr of data bits
            
            % To be placed in the label
            labelString = 'Data bits: ';
            
            % Randomly generated message 
            app.dataBits = round(rand(1, nr_dB)); %d_ndB d_ndB-1... d_2 d_1
            for i = 1:nr_dB
                labelString = append(labelString, num2str(app.dataBits(i)));
            end
            labelString = append(labelString, newline);
            
            % Parity bit coverage matrice 
            M = dec2bin(1:1:2^app.n-1) - '0'; 
            O = ones(1, app.n);
            Sr = O*M'; % Parity bit coverage sum, row wise
            app.C_i = []; % Control bits place in string
            app.D_i = []; % Data bits place in string
            
            for i = 1:len
                if(Sr(i)) == 1
                    app.C_i = [app.C_i, i];
                else
                    app.D_i = [app.D_i, i];
                end
            end
            
            % Calculating control bits
            app.controlBits = zeros(1, app.n); 
            for r = 1:app.n
                sum = 0;
                for c = 1:len
                    if (M(c, (app.n+1-r)) == 1 && c ~= app.C_i(r))
                        for e = 1:nr_dB
                            if(app.D_i(e) == c)
                                sum = sum + app.dataBits(nr_dB+1-e);
                            end
                        end
                    end
                end
                app.controlBits(r) = mod(sum, 2);
            end
            
            % Encoding the message
            app.bits = zeros(1, len); % Encoded message
            
            for i = 1:app.n
                app.bits(len+1- app.C_i(i)) = app.controlBits(i);
            end
                        
            for j = 1:nr_dB
                app.bits(len+1-app.D_i(j)) = app.dataBits(nr_dB+1-j);
            end  
            labelString = append(labelString, 'Encoded bits: ');
            for i = 1:len
                labelString = append(labelString, num2str(app.bits(i)));
            end
            
            app.Label_2.Text = labelString;
        end
        
        function changeBitAndVerify(app)
            labelString = '';
            p = app.EditField_3.Value;
            labelString = append(labelString, 'The Flipped bit is: ');
            labelString = append(labelString, num2str(p));
            labelString = append(labelString, newline);
            len = 2^app.n - 1;
            nr_dB = len - app.n;
            M = dec2bin(1:1:2^app.n-1) - '0';
            
            % Flipping the requested bit
            if app.bits(len+1-p) == 0
                app.bits(len+1-p) = 1;
            else
                app.bits(len+1-p) = 0;
            end
            
            labelString = append(labelString, 'The new message is: ');
            for i = 1:len
                labelString = append(labelString, num2str(app.bits(i)));
            end
            labelString = append(labelString, newline);
            
            for i = 1:app.n
                if app.C_i(i) == p
                    app.controlBits(i) = app.bits(len+1-p);
                end
            end
            
            for i = 1:nr_dB
                if app.D_i(i) == p
                    app.dataBits(nr_dB+1-i) = app.bits(len+1-p);
                end
            end
            
            %--------------------------------------------------------------------%
            % Verification
            s = []; % Symdromes
            for r = 1:app.n
                sum = 0;
                for c = 1:len
                    if (M(c, (app.n+1-r)) == 1 && c ~= app.C_i(r))
                        for e = 1:nr_dB
                            if(app.D_i(e) == c)
                                sum = sum + app.dataBits(nr_dB+1-e);
                            end
                        end
                    end
                end
                
                sum = sum + app.controlBits(r);
                s(app.n+1-r) = mod(sum, 2);
            end
            
            labelString = append(labelString, 'The syndromes are: ');
            for i = 1:app.n
                labelString = append(labelString, num2str(s(i)));
            end
            labelString = append(labelString, newline);
            
            % Calculating flipped bit position
            flipped_bit_position = 0;
            for i = 1:app.n
                flipped_bit_position = flipped_bit_position + s(app.n+1-i)*2^(i-1);
            end
            labelString = append(labelString, 'Flipped bit position: ');
            labelString = append(labelString, num2str(flipped_bit_position));
            labelString = append(labelString, newline);
            
            app.Label_2.Text = labelString;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateandEncodeButton
        function GenerateandEncodeButtonPushed(app, event)
            genAndEncode(app)
        end

        % Button pushed function: FlipBitButton
        function FlipBitButtonPushed(app, event)
            changeBitAndVerify(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GenerateandEncodeButton = uibutton(app.UIFigure, 'push');
            app.GenerateandEncodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateandEncodeButtonPushed, true);
            app.GenerateandEncodeButton.Text = 'Generate and Encode';
            app.FlipBitButton = uibutton(app.UIFigure, 'push');
            app.FlipBitButton.ButtonPushedFcn = createCallbackFcn(app, @FlipBitButtonPushed, true);
            app.FlipBitButton.Text = 'Flip Bit';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hammingcodes_IVM_DI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end